classdef final_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ApplyFilterButton            matlab.ui.control.StateButton
        TabGroup_2                   matlab.ui.container.TabGroup
        Amp_OGTab_2                  matlab.ui.container.Tab
        UIAxes6_2                    matlab.ui.control.UIAxes
        Filt_Mag_TivaTab             matlab.ui.container.Tab
        UIAxes5_2                    matlab.ui.control.UIAxes
        FIlt_Pha_TivaTab             matlab.ui.container.Tab
        UIAxes10                     matlab.ui.control.UIAxes
        Filt_TTab_2                  matlab.ui.container.Tab
        UIAxes4_2                    matlab.ui.control.UIAxes
        Amp_FiltTab_2                matlab.ui.container.Tab
        UIAxes9                      matlab.ui.control.UIAxes
        TabGroup                     matlab.ui.container.TabGroup
        Amp_OGTab                    matlab.ui.container.Tab
        UIAxes6                      matlab.ui.control.UIAxes
        Filt_MagTab                  matlab.ui.container.Tab
        UIAxes5                      matlab.ui.control.UIAxes
        Filt_PhaTab                  matlab.ui.container.Tab
        UIAxes7                      matlab.ui.control.UIAxes
        Filt_TTab                    matlab.ui.container.Tab
        UIAxes4                      matlab.ui.control.UIAxes
        Amp_FiltTab                  matlab.ui.container.Tab
        UIAxes8                      matlab.ui.control.UIAxes
        SampleRateCheckBox           matlab.ui.control.CheckBox
        Tree2                        matlab.ui.container.CheckBoxTree
        SignalsNode                  matlab.ui.container.TreeNode
        CapturedNode                 matlab.ui.container.TreeNode
        LoadedNode                   matlab.ui.container.TreeNode
        Tree                         matlab.ui.container.CheckBoxTree
        VariablesNode                matlab.ui.container.TreeNode
        Captured_SignalNode          matlab.ui.container.TreeNode
        Imported_SignalNode          matlab.ui.container.TreeNode
        Realtime_filterNode          matlab.ui.container.TreeNode
        Static_filterNode            matlab.ui.container.TreeNode
        TipoFiltroListBox_2          matlab.ui.control.ListBox
        TipoFiltroListBox_2Label     matlab.ui.control.Label
        RealtimeFilterButton         matlab.ui.control.Button
        OrdenSpinner_2               matlab.ui.control.Spinner
        OrdenSpinner_2Label          matlab.ui.control.Label
        Frec_Corte_SupSlider_2       matlab.ui.control.Slider
        Frec_Corte_SupSlider_2Label  matlab.ui.control.Label
        Frec_CorteSlider_2           matlab.ui.control.Slider
        Frec_CorteSlider_2Label      matlab.ui.control.Label
        OrdenSpinner                 matlab.ui.control.Spinner
        OrdenSpinnerLabel            matlab.ui.control.Label
        Frec_Corte_SupSlider         matlab.ui.control.Slider
        Frec_Corte_SupSliderLabel    matlab.ui.control.Label
        Frec_CorteSlider             matlab.ui.control.Slider
        Frec_CorteSliderLabel        matlab.ui.control.Label
        MagendBSwitch                matlab.ui.control.Switch
        MagendBSwitchLabel           matlab.ui.control.Label
        SaveMatButton                matlab.ui.control.Button
        SaveCSVButton                matlab.ui.control.Button
        FiltradoTivaButtonGroup      matlab.ui.container.ButtonGroup
        RechazaBandaButton_2         matlab.ui.control.RadioButton
        PasaBandaButton_2            matlab.ui.control.RadioButton
        PasaBajasButton_2            matlab.ui.control.RadioButton
        PasaAltasButton_2            matlab.ui.control.RadioButton
        FiltradoButtonGroup          matlab.ui.container.ButtonGroup
        RechazaBandaButton           matlab.ui.control.RadioButton
        PasaBandaButton              matlab.ui.control.RadioButton
        PasaBajasButton              matlab.ui.control.RadioButton
        PasaAltasButton              matlab.ui.control.RadioButton
        TipoFiltroListBox            matlab.ui.control.ListBox
        TipoFiltroListBoxLabel       matlab.ui.control.Label
        RealTimePlotCheckBox         matlab.ui.control.CheckBox
        TivaSampleRateSpinner        matlab.ui.control.Spinner
        TivaSampleRateSpinnerLabel   matlab.ui.control.Label
        Time_IntSpinner              matlab.ui.control.Spinner
        Time_IntSpinnerLabel         matlab.ui.control.Label
        LoadMatButton                matlab.ui.control.Button
        GetTivaButton                matlab.ui.control.Button
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        S=serialport('COM7',230400);
        TivaSample=2
        GrafTiva=0;
        SampRate=1000;
        Flag_db=0;
        Flag_Filt=0;
        RealTime=0;
        Filtrado="HIGH";
        Filtrado_tiva="HIGH";
        Wcorteinf=0.1;
        Wcortesup=0.3;
        ordenfiltro=1;
        Filtering=1
        TipoFiltro="0";
        Wcorte=0.1
        Wcorteinf_tiva=0.1;
        Wcortesup_tiva=0.3;
        Wcorte_tiva=0.1
        num_tiva;
        den_tiva;
        num_tiva_1 = zeros(1,5);
        den_tiva_1 = zeros(1,5);
        orden_tiva=1;
        filtro_mat;
        str = "";
        dBen=0;
        bajoDemanda=0;
        %Resumen Vars a exportar:
        %SampRate es el sample rate que tiene la tiva
        %sign_tiva y sign_mat son las señales que vienen de la tiva y del
        %.mat respectivamente
        %Misma dinamica con time_tiva y time_data
        %P5 espectro amp original del .mat f_mat son las frec
        %P1 espectro amp original del .mat f_tiva son las frec
        %P3 espectro amp filtrado de la tiva la frec es f_tivafiltro
        %P9 espectro amp filtrado del .mat la frec es f_matfiltro
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetTivaButton
        function GetTivaButtonPushed(app, event)

            global sign_tiva
            global time_tiva
            global filtro_main
            global num
            global den
            global P1
            global f_tiva
            
            t = timer;
            t.StartDelay=1;
            t.TimerFcn=@(~,~)drawnow; 
            t.Period=4;
            n=round(app.SampRate*app.TivaSample);
            sign_tiva=zeros(n,1);
            time_tiva=1/app.SampRate*[0:(n-1)];
            i=1;
            if app.bajoDemanda==1
            while app.bajoDemanda==1
            flush(app.S);
            i=1;
            for i=1:length(sign_tiva)
              sign_tiva(i)=3.3/4095*str2double(readline(app.S));
              plot(app.UIAxes,time_tiva,sign_tiva)
              drawnow limitrate;
              i=i+1;
            end
                N=length(sign_tiva);
                X1=fft(sign_tiva);
                P2 = abs(X1/N);
                P1 = P2(1:N/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f_tiva = app.SampRate*(0:(N/2))/N;
                stem(app.UIAxes6,f_tiva,P1)
            end
            else
                flush(app.S);
                for i=1:length(sign_tiva)
                    sign_tiva(i)=3.3/4095*str2double(readline(app.S));
                end
                plot(app.UIAxes,time_tiva,sign_tiva)
                N=length(sign_tiva);
                X1=fft(sign_tiva);
                P2 = abs(X1/N);
                P1 = P2(1:N/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f_tiva = app.SampRate*(0:(N/2))/(N/2);
                stem(app.UIAxes6,f_tiva,P1)
            end
        end

        % Value changed function: Time_IntSpinner
        function Time_IntSpinnerValueChanged(app, event)
            app.TivaSample = app.Time_IntSpinner.Value;
            
        end

        % Button pushed function: LoadMatButton
        function LoadMatButtonPushed(app, event)
            global sign_data
            global FsData
            global time_data
            global P5
            global f_mat
            [file,path]=uigetfile('*.mat');
            str_file = convertCharsToStrings(file);
            str_path = convertCharsToStrings(path);
            load(str_path+str_file);
            FsData=Fs;
            sign_data=x;
            k=length(sign_data);
            time_data=1/FsData*[0:k-1]';
            plot(app.UIAxes3,time_data,sign_data)
            N=length(sign_data);
                X2=fft(sign_data);
                P4 = abs(X2/N);
                P5 = P4(1:N/2+1);
                %P3 Y f_mat VARIABLES ESPECTRO .mat
                P5(2:end-1) = 2*P5(2:end-1);
                f_mat = FsData*(0:(N/2))/(N/2);
                stem(app.UIAxes6_2,f_mat,P5) 
        end

        % Value changing function: TivaSampleRateSpinner
        function TivaSampleRateSpinnerValueChanging(app, event)
            app.SampRate = event.Value;
            app.Frec_CorteSlider.Limits = [0+0.01 app.SampRate*0.5-0.01];
            app.Frec_Corte_SupSlider.Limits = [0+0.01 app.SampRate*0.5-0.01];
            app.Frec_CorteSlider_2.Limits = [0+0.01 app.SampRate*0.5-0.01];
            app.Frec_Corte_SupSlider_2.Limits = [0+0.01 app.SampRate*0.5-0.01];
            app.str=num2str(app.num_tiva_1(1),6)+","+num2str(app.num_tiva_1(2),6)+","+num2str(app.num_tiva_1(3),6) ... 
            +","+num2str(app.num_tiva_1(4),6)+","+num2str(app.num_tiva_1(5),6)+","+num2str(app.den_tiva_1(1),6) ...
            +","+num2str(app.den_tiva_1(2),6)+","+num2str(app.den_tiva_1(3),6)+","+num2str(app.den_tiva_1(4),6) ...
            +","+num2str(app.den_tiva_1(5),6)+","+num2str(app.SampleRateCheckBox.Value)+","+num2str(1000/app.TivaSampleRateSpinner.Value,0)
            writeline(app.S,app.str)
        end

        % Value changed function: Frec_CorteSlider
        function Frec_CorteSliderValueChanged(app, event)
            app.Wcorteinf = app.Frec_CorteSlider.Value;
            app.Wcorteinf=app.Wcorteinf/(app.SampRate*0.5);
            
        end

        % Value changed function: OrdenSpinner
        function OrdenSpinnerValueChanged(app, event)
            app.ordenfiltro = app.OrdenSpinner.Value;
            
        end

        % Callback function
        function FilteringSwitchValueChanged(app, event)
            value = app.FilteringSwitch.Value;
            if value=="Off"
                app.Filtering=0;
            else
                app.Filtering=1;
            end
        end

        % Value changed function: Frec_Corte_SupSlider
        function Frec_Corte_SupSliderValueChanged(app, event)
            app.Wcortesup = app.Frec_Corte_SupSlider.Value;
            app.Wcortesup=app.Wcortesup/(app.SampRate*0.5);
            
        end

        % Button pushed function: RealtimeFilterButton
        function RealtimeFilterButtonPushed(app, event)
        
        app.orden_tiva = app.OrdenSpinner_2.Value;
        app.Wcortesup_tiva = app.Frec_Corte_SupSlider_2.Value;
        app.Wcortesup_tiva=app.Wcortesup_tiva/(app.SampRate*0.5);
        app.Wcorteinf_tiva = app.Frec_CorteSlider_2.Value;
        app.Wcorteinf_tiva=app.Wcorteinf_tiva/(app.SampRate*0.5);
        if app.PasaAltasButton_2.Value==1
            app.Filtrado_tiva='high';
            app.Wcorte_tiva=app.Wcorteinf_tiva
        elseif app.PasaBajasButton_2.Value==1
            app.Filtrado_tiva='low';
            app.Wcorte_tiva=app.Wcorteinf_tiva
        elseif app.PasaBandaButton_2.Value==1
            app.Filtrado_tiva='bandpass';
            app.Wcorte_tiva=[app.Wcorteinf_tiva app.Wcortesup_tiva]
        elseif app.RechazaBandaButton_2.Value==1
            app.Filtrado_tiva='stop';
            app.Wcorte_tiva=[app.Wcorteinf_tiva app.Wcortesup_tiva]
        end    
        value = app.TipoFiltroListBox_2.Value;
        if value=="IIR_Butterworth"
            if ((app.Filtrado_tiva=="bandpass") || (app.Filtrado_tiva=="stop"))
                if app.orden_tiva > 2
                    app.orden_tiva =2;
                end
            end
            [app.num_tiva,app.den_tiva]=butter(app.orden_tiva,app.Wcorte_tiva,app.Filtrado_tiva);
        elseif value=="IIR_Chebyshev1"
            if ((app.Filtrado_tiva=="bandpass") || (app.Filtrado_tiva=="stop"))
                if app.orden_tiva > 2
                    app.orden_tiva =2;
                end
            end
            [app.num_tiva,app.den_tiva]=cheby1(app.orden_tiva,0.1,app.Wcorte_tiva,app.Filtrado_tiva);
        elseif value=="IIR_Chebyshev2"
            if ((app.Filtrado_tiva=="bandpass") || (app.Filtrado_tiva=="stop"))
                if app.orden_tiva > 2
                    app.orden_tiva =2;
                end
            end
            [app.num_tiva,app.den_tiva]=cheby2(app.orden_tiva,0.1,app.Wcorte_tiva,app.Filtrado_tiva);
        end
        app.num_tiva_1 = zeros(1,5);
        app.den_tiva_1 = zeros(1,5);
            if length(app.num_tiva) == 5
            app.num_tiva_1 = app.num_tiva;
            app.den_tiva_1 = app.den_tiva;
            else
                for i=1:length(app.num_tiva)
                    app.num_tiva_1(i) = app.num_tiva(i);
                end
                for i=1:(5-length(app.num_tiva))
                    app.num_tiva_1(i+length(app.num_tiva)) = 0;
                end
                for i=1:length(app.den_tiva)
                    app.den_tiva_1 (i) = app.den_tiva(i);
                end
                for i=1:(5-length(app.den_tiva))
                    app.den_tiva_1 (i+length(app.den_tiva)) = 0;
                end
            end
app.str=num2str(app.num_tiva_1(1),6)+","+num2str(app.num_tiva_1(2),6)+","+num2str(app.num_tiva_1(3),6) ... 
+","+num2str(app.num_tiva_1(4),6)+","+num2str(app.num_tiva_1(5),6)+","+num2str(app.den_tiva_1(1),6) ...
+","+num2str(app.den_tiva_1(2),6)+","+num2str(app.den_tiva_1(3),6)+","+num2str(app.den_tiva_1(4),6) ...
+","+num2str(app.den_tiva_1(5),6)+","+num2str(app.SampleRateCheckBox.Value)+","+num2str(1000/app.TivaSampleRateSpinner.Value,0)
writeline(app.S,app.str)

%ESPECTROS FILTRO TIVA
                [hM,wM]= freqz(app.num_tiva, app.den_tiva);
                if app.dBen==0
                plot(app.UIAxes5_2,app.SampRate*(wM/(2*pi)),abs(hM));%MAG sin DB
                else
                 plot(app.UIAxes5_2,app.SampRate*(wM/(2*pi)),20*log(abs(hM)));   
                end
                plot(app.UIAxes10,app.SampRate*(wM/(2*pi)),angle(hM));%Fase
        

        end

        % Selection changed function: FiltradoTivaButtonGroup
        function FiltradoTivaButtonGroupSelectionChanged(app, event)
            if app.PasaAltasButton_2.Value==1
                app.Filtrado_tiva='HIGH';
                app.Wcorte_tiva=app.Wcorteinf_tiva
            elseif app.PasaBajasButton_2.Value==1
                app.Filtrado_tiva='LOW';
                app.Wcorte_tiva=app.Wcorteinf_tiva
            elseif app.PasaBandaButton_2.Value==1
                app.Filtrado_tiva='bandpass';
                app.Wcorte_tiva=[app.Wcorteinf_tiva app.Wcortesup_tiva]
            elseif app.RechazaBandaButton_2.Value==1
                app.Filtrado_tiva='stop';
                app.Wcorte_tiva=[app.Wcorteinf_tiva app.Wcortesup_tiva]
            end
            
        end

        % Value changed function: Frec_CorteSlider_2
        function Frec_CorteSlider_2ValueChanged(app, event)
            app.Wcorteinf_tiva = app.Frec_CorteSlider_2.Value;
            app.Wcorteinf_tiva=app.Wcorteinf_tiva/(app.SampRate*0.5);
            
        end

        % Value changed function: Frec_Corte_SupSlider_2
        function Frec_Corte_SupSlider_2ValueChanged(app, event)
            app.Wcortesup_tiva = app.Frec_Corte_SupSlider_2.Value;
            app.Wcortesup_tiva=app.Wcortesup_tiva/(app.SampRate*0.5);
        end

        % Value changed function: OrdenSpinner_2
        function OrdenSpinner_2ValueChanged(app, event)
            app.orden_tiva = app.OrdenSpinner_2.Value;
            
        end

        % Button pushed function: SaveMatButton
        function SaveMatButtonPushed(app, event)
            global num;
            global den;
            global sign_tiva;
            global FsData;
            global sign_data;
            numer = app.num_tiva;
            deno = app.den_tiva;
            captured_sign = sign_tiva;
            captured_rate = app.SampRate;
            loaded_sign = sign_data; 
            loaded_rate = FsData;
            text = zeros(1,length(app.Tree.CheckedNodes));
            if ~isempty(app.Tree.CheckedNodes)
                for i=1:length(app.Tree.CheckedNodes)
                    %fprintf(app.Tree.CheckedNodes(i).Text)
                    if app.Tree.CheckedNodes(i).Text == "Captured_Signal"
                        text(i) = 1;
                    elseif app.Tree.CheckedNodes(i).Text == "Imported_Signal"
                        text(i) = 2;
                    elseif app.Tree.CheckedNodes(i).Text == "Realtime_filter"
                        text(i) = 3;
                    elseif app.Tree.CheckedNodes(i).Text == "Static_filter"
                        text(i) = 4;
                    else
                        text(i) = 5;
                    end
                end
                [file,path]=uiputfile('*.mat')
                str_file = convertCharsToStrings(file);
                str_path = convertCharsToStrings(path);
                
                
                switch(text(1))
                    case 1
                        if length(text) == 1
                            save(str_path+str_file,"captured_sign","captured_rate");
                        end
                        if length(text) >= 2
                        switch(text(2))
                            case 2
                                save(str_path+str_file,"captured_sign","captured_rate","loaded_rate","loaded_sign");
                                if length(text) >= 3
                                switch(text(3))
                                    case 3
                                        save(str_path+str_file,"captured_sign","captured_rate","loaded_rate","loaded_sign","numer","deno");
                                    case 4
                                        save(str_path+str_file,"captured_sign","captured_rate","loaded_rate","loaded_sign","num","den");
                                    end
                                end
                            case 3
                                save(str_path+str_file,"captured_sign","captured_rate","numer","deno");
                                if length(text) >= 3
                                switch(text(3))
                                    case 2
                                        save(str_path+str_file,"captured_sign","captured_rate","captured_sign","captured_rate","numer","deno");
                                    case 4
                                        save(str_path+str_file,"captured_sign","captured_rate","num","den","numer","deno");
                                    end
                                end
                            case 4
                                save(str_path+str_file,"captured_sign","captured_rate","num","den");
                                if length(text) >= 3
                                switch(text(3))
                                    case 2
                                        save(str_path+str_file,"captured_sign","captured_rate","num","den","loaded_rate","loaded_sign");
                                    case 3
                                        save(str_path+str_file,"captured_sign","captured_rate","num","den","numer","deno");
                                    end
                                end
                        end
                        end
                    case 2
                    if length(text) >= 1
                        save(str_path+str_file,"loaded_sign","loaded_rate");
                    end
                        if length(text) >= 2    
                        switch(text(2))
                            case 1
                                save(str_path+str_file,"captured_sign","captured_rate","loaded_rate","loaded_sign");
                                if length(text) >= 3
                                switch(text(3))
                                    case 3
                                        save(str_path+str_file,"captured_sign","captured_rate","loaded_rate","loaded_sign","numer","deno");
                                    case 4
                                        save(str_path+str_file,"captured_sign","captured_rate","loaded_rate","loaded_sign","num","den");
                                    end
                                end
                            case 3
                                save(str_path+str_file,"loaded_rate","loaded_sign","numer","deno");
                                if length(text) >= 3
                                switch(text(3))
                                    case 1
                                        save(str_path+str_file,"captured_sign","captured_rate","loaded_rate","loaded_sign","numer","deno");
                                    case 4
                                        save(str_path+str_file,"loaded_rate","loaded_sign","numer","deno","num","den");
                                    end
                                end
                            case 4
                                save(str_path+str_file,"loaded_rate","loaded_sign","num","den");
                                if length(text) >= 3
                                switch(text(3))
                                    case 1
                                        save(str_path+str_file,"captured_sign","captured_rate","loaded_rate","loaded_sign","num","den");
                                    case 3
                                        save(str_path+str_file,"loaded_rate","loaded_sign","filtro_mat""filtro_tiva");
                                    end
                                end
                        end
                        end
                    case 3
                    if length(text) >= 1
                        save(str_path+str_file,"numer","deno");
                    end
                        if length(text) >= 2
                        switch(text(2))
                            case 1
                                save(str_path+str_file,"captured_sign","captured_rate","numer","deno");
                                if length(text) >= 3
                                switch(text(3))
                                    case 2
                                        save(str_path+str_file,"captured_sign","captured_rate","numer","deno","loaded_rate","loaded_sign");
                                    case 4
                                        save(str_path+str_file,"captured_sign","captured_rate","num","den","numer","deno");
                                    end
                                end
                            case 2
                                save(str_path+str_file,"loaded_rate","loaded_sign","numer","deno");
                                if length(text) >= 3
                                switch(text(3))
                                    case 1
                                        save(str_path+str_file,"captured_sign","captured_rate","loaded_rate","loaded_sign","numer","deno");
                                    case 4
                                        save(str_path+str_file,"loaded_rate","loaded_sign","num","den","numer","deno");
                                    end
                                end
                            case 4
                                save(str_path+str_file,"num","den","numer","deno");
                                if length(text) >= 3
                                switch(text(3))
                                    case 1
                                        save(str_path+str_file,"captured_sign","captured_rate","num","den","numer","deno");
                                    case 2
                                        save(str_path+str_file,"loaded_rate","loaded_sign","num","den","numer","deno");
                                    end
                                end
                        end      
                        end
                    case 4
                    if length(text) >= 1
                        save(str_path+str_file,"num","den");
                    end
                        if length(text) >= 2
                        switch(text(2))
                            case 1
                                save(str_path+str_file,"captured_sign","captured_rate","num","den");
                                if length(text) >= 3
                                switch(text(3))
                                    case 2
                                        save(str_path+str_file,"captured_sign","captured_rate","num","den","loaded_rate","loaded_sign");
                                    case 3
                                        save(str_path+str_file,"captured_sign","captured_rate","num","den","numer","deno");
                                    end
                                end
                            case 2
                                save(str_path+str_file,"loaded_rate","loaded_sign","num","den");
                                if length(text) >= 3
                                switch(text(3))
                                    case 1
                                        save(str_path+str_file,"captured_sign","captured_rate","num","den","loaded_rate","loaded_sign");
                                    case 3
                                        save(str_path+str_file,"loaded_rate","loaded_sign","num","den","numer","deno");
                                    end
                                end
                            case 3
                                save(str_path+str_file,"num","den","numer","deno");
                                if length(text) >= 3
                                switch(text(3))
                                    case 1
                                        save(str_path+str_file,"captured_sign","captured_rate","num","den","numer","deno");
                                    case 2
                                        save(str_path+str_file,"loaded_rate","loaded_sign","num","den","numer","deno");
                                    end
                                end
                        end
                        end
                    case 5
                        save(str_path+str_file,"captured_sign","captured_rate","loaded_rate","loaded_sign","num","den","numer","deno");
                end
            end
            
        end

        % Button pushed function: SaveCSVButton
        function SaveCSVButtonPushed(app, event)
            global sign_tiva;
            global FsData;
            global sign_data;
            global filtro_main;
            global filtro_data
            global time_tiva;
            global time_data;
            captured_sign = sign_tiva;
            captured_filter = filtro_main;
            loaded_sign = sign_data; 
            loaded_filter = filtro_data;
            [file,path]=uiputfile('*.*');
            str_file = convertCharsToStrings(file);
            str_path = convertCharsToStrings(path);
            text = zeros(1,length(app.Tree2.CheckedNodes));
            if ~isempty(app.Tree2.CheckedNodes)
                for i=1:length(app.Tree2.CheckedNodes)
                    if app.Tree2.CheckedNodes(i).Text == "Captured"
                        text(i) = 1;
                    else
                        text(i) = 3;
                    end
                end
                if length(text) == 1
                    if text(1) ==1
                        fprintf("%d\n",length(captured_sign));
                        fprintf("%d\n",length(captured_filter));
                        fprintf("%d\n",length(time_tiva));
                        csvwrite(str_path+str_file,[captured_sign,captured_filter,time_tiva']);
                    else
                        csvwrite(str_path+str_file,[loaded_sign,loaded_filter,time_data]);
                    end
                elseif length(text) == 3
                    csvwrite(str_path+str_file,[captured_sign,captured_filter,time_tiva']);
                    csvwrite(str_path+"load_"+str_file,[loaded_sign,loaded_filter,time_data]);
                else 
                    fprintf('no selecciono nada para guardar\n')
                end
            end
        end

        % Value changed function: SampleRateCheckBox
        function SampleRateCheckBoxValueChanged(app, event)
app.str=num2str(app.num_tiva_1(1),6)+","+num2str(app.num_tiva_1(2),6)+","+num2str(app.num_tiva_1(3),6) ... 
+","+num2str(app.num_tiva_1(4),6)+","+num2str(app.num_tiva_1(5),6)+","+num2str(app.den_tiva_1(1),6) ...
+","+num2str(app.den_tiva_1(2),6)+","+num2str(app.den_tiva_1(3),6)+","+num2str(app.den_tiva_1(4),6) ...
+","+num2str(app.den_tiva_1(5),6)+","+num2str(app.SampleRateCheckBox.Value)+","+num2str(1000/app.TivaSampleRateSpinner.Value,0)
writeline(app.S,app.str)
        end

        % Value changed function: ApplyFilterButton
        function ApplyFilterButtonValueChanged(app, event)
            global sign_tiva
            global time_tiva
            global filtro_main
            global filtro_data
            global num
            global den
            global sign_data
            global FsData
            global time_data
            global P3
            global f_tivafiltro
            global f_matfiltro
            global P9
            app.ordenfiltro = app.OrdenSpinner.Value;
             if app.PasaAltasButton.Value==1
                app.Filtrado='HIGH';
                app.Wcorte=app.Wcorteinf
            elseif app.PasaBajasButton.Value==1
                app.Filtrado='LOW';
                app.Wcorte=app.Wcorteinf
            elseif app.PasaBandaButton.Value==1
                app.Filtrado='bandpass';
                app.Wcorte=[app.Wcorteinf app.Wcortesup]
            elseif app.RechazaBandaButton.Value==1
                app.Filtrado='stop';
                app.Wcorte=[app.Wcorteinf app.Wcortesup]
            end
            value = app.TipoFiltroListBox.Value;
            if value=="FIR_Window"
                fprintf('%s\n',app.Filtrado)
                num=fir1(app.ordenfiltro,app.Wcorte,app.Filtrado)
                den=1;
                efecto=0;
            elseif value=="FIR_Ellip"
                fprintf('%s\n',app.Filtrado)
                [num,den]=ellip(app.ordenfiltro,5,50,app.Wcorte,app.Filtrado);
                efecto=0;
            elseif value=="IIR_Butterworth"
                fprintf('%s\n',app.Filtrado)
                [num,den]=butter(app.ordenfiltro,app.Wcorte,app.Filtrado)
                efecto=0;
            elseif value=="IIR_Chebyshev1"
                fprintf('%s\n',app.Filtrado)
                [num,den]=cheby1(app.ordenfiltro,1,app.Wcorte,app.Filtrado);
                efecto=0;
            elseif value=="IIR_Chebyshev2"
                fprintf('%s\n',app.Filtrado)
                [num,den]=cheby2(app.ordenfiltro,1,app.Wcorte,app.Filtrado);
                efecto=0;
            elseif value=="Eco"
                efecto=1;
                temp=delayseq(sign_tiva,.1,app.SampRate);
                filtro_main=(sign_tiva+0.5*temp)/max(sign_tiva);
                
                temp2=delayseq(sign_data,.1,FsData);
                filtro_data=(sign_data+0.5*temp2)/max(sign_data);
               
            elseif value=="Distor"
               efecto=1
               index=length(sign_tiva);
               for ip=1:index
                   if abs(sign_tiva(ip))<=0.7
                       filtro_main(ip)=sign_tiva(ip)
                   else
                       filtro_main(ip)=0.7;
                   end
               end
               
               for ip=1:length(sign_data)
                   if abs(sign_data(ip))<=0.7
                       filtro_data(ip)=sign_data(ip)
                   else
                       filtro_data(ip)=0.7;
                   end
               end
               
            elseif value=="Vibrato"
                efecto=1;
                slope=1;
                tresh=0.5;
                rt=0.01;
                at=0.4;
                
                xd(1)=0;
                for k=2:length(sign_tiva)
                    a=abs(sign_tiva(k))-xd(k-1);
                    if a<0, a=0; end
                    xd(k)=xd(k-1)*(1-rt)+at*a;
                    if xd(k)>tresh
                        f(k)=10^(-slope*(log10(xd(k))-log10(tresh)));
                    else f(k)=1;
                    end
                    filtro_main(k)=sign_tiva(k)*f(k);
                end
                
                xd(1)=0;
                for k=2:length(sign_data)
                    a=abs(sign_data(k))-xd(k-1);
                    if a<0, a=0; end
                    xd(k)=xd(k-1)*(1-rt)+at*a;
                    if xd(k)>tresh
                        f(k)=10^(-slope*(log10(xd(k))-log10(tresh)));
                    else f(k)=1;
                    end
                    filtro_data(k)=sign_data(k)*f(k);
                end
                
            end
            if app.Filtering==1 
                if efecto==0
                filtro_main=filter(num,den,sign_tiva)
                if FsData == app.SampRate
                filtro_data=filter(num,den,sign_data)
                end
                plot(app.UIAxes4,time_tiva,filtro_main)
                plot(app.UIAxes4_2,time_data,filtro_data)
                [hT,wT]= freqz(num, den);
                if app.dBen==0
                plot(app.UIAxes5,app.SampRate*(wT/(2*pi)),abs(hT));%MAG sin DB
                else
                 plot(app.UIAxes5,app.SampRate*(wT/(2*pi)),20*log(abs(hT)));   
                end
                plot(app.UIAxes7,app.SampRate*(wT/(2*pi)),angle(hT));%Fase
                else 
                    plot(app.UIAxes4,time_tiva,filtro_main)
                    plot(app.UIAxes4_2,time_data,filtro_data)
                    
                end
                prueba=length(filtro_main)
                if prueba ~=0
                X2=fft(filtro_main);
                P4 = abs(X2/prueba)
                P10 = P4(1:(prueba/2)+1)
                P10(2:end-1) = 2*P10(2:end-1);
                f_tivafiltro = app.SampRate*(0:(prueba/2))/(prueba/2);
                stem(app.UIAxes8,f_tivafiltro,P10)
                end
                
                prueba=length(filtro_data);
                if prueba ~=0
                X5=fft(filtro_data);
                P8 = abs(X5/prueba);
                P9 = P8(1:prueba/2+1);
                P9(2:end-1) = 2*P9(2:end-1);
                f_matfiltro = FsData*(0:(prueba/2))/(prueba/2);
                stem(app.UIAxes9,f_matfiltro,P9)
                end
              
            end
            
        end

        % Value changed function: MagendBSwitch
        function MagendBSwitchValueChanged(app, event)
            value = app.MagendBSwitch.Value;
            if value=="Off"
                app.dBen=0;
            else
                app.dBen=1;
            end
        end

        % Value changed function: RealTimePlotCheckBox
        function RealTimePlotCheckBoxValueChanged(app, event)
            app.bajoDemanda = app.RealTimePlotCheckBox.Value
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Captured Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Loaded Signal')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.GetTivaButton = uibutton(app.UIFigure, 'push');
            app.GetTivaButton.ButtonPushedFcn = createCallbackFcn(app, @GetTivaButtonPushed, true);
            app.GetTivaButton.Text = 'GetTiva';
            app.LoadMatButton = uibutton(app.UIFigure, 'push');
            app.LoadMatButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMatButtonPushed, true);
            app.LoadMatButton.Text = 'LoadMat';
            app.Time_IntSpinnerLabel = uilabel(app.UIFigure);
            app.Time_IntSpinnerLabel.Text = 'Time_Int';
            app.Time_IntSpinner = uispinner(app.UIFigure);
            app.Time_IntSpinner.Limits = [0.01 5];
            app.Time_IntSpinner.ValueChangedFcn = createCallbackFcn(app, @Time_IntSpinnerValueChanged, true);
            app.Time_IntSpinner.Value = 0.01;
            app.TivaSampleRateSpinnerLabel = uilabel(app.UIFigure);
            app.TivaSampleRateSpinnerLabel.Text = 'TivaSampleRate';
            app.TivaSampleRateSpinner = uispinner(app.UIFigure);
            app.TivaSampleRateSpinner.ValueChangingFcn = createCallbackFcn(app, @TivaSampleRateSpinnerValueChanging, true);
            app.TivaSampleRateSpinner.Limits = [200 1000];
            app.TivaSampleRateSpinner.Value = 1000;
            app.RealTimePlotCheckBox = uicheckbox(app.UIFigure);
            app.RealTimePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @RealTimePlotCheckBoxValueChanged, true);
            app.RealTimePlotCheckBox.Text = 'RealTimePlot';
            app.TipoFiltroListBoxLabel = uilabel(app.UIFigure);
            app.TipoFiltroListBoxLabel.Text = 'TipoFiltro';
            app.TipoFiltroListBox = uilistbox(app.UIFigure);
            app.TipoFiltroListBox.Items = {'FIR_Window', 'FIR_Ellip', 'IIR_Butterworth', 'IIR_Chebyshev1', 'IIR_Chebyshev2', 'Eco', 'Distor', 'Vibrato'};
            app.TipoFiltroListBox.Value = 'FIR_Window';
            app.FiltradoButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltradoButtonGroup.Title = 'Filtrado';
            app.PasaAltasButton = uiradiobutton(app.FiltradoButtonGroup);
            app.PasaAltasButton.Text = 'PasaAltas';
            app.PasaBajasButton = uiradiobutton(app.FiltradoButtonGroup);
            app.PasaBajasButton.Text = 'PasaBajas';
            app.PasaBandaButton = uiradiobutton(app.FiltradoButtonGroup);
            app.PasaBandaButton.Text = 'PasaBanda';
            app.RechazaBandaButton = uiradiobutton(app.FiltradoButtonGroup);
            app.RechazaBandaButton.Text = 'RechazaBanda';
            app.RechazaBandaButton.Value = true;
            app.FiltradoTivaButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltradoTivaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiltradoTivaButtonGroupSelectionChanged, true);
            app.FiltradoTivaButtonGroup.Title = 'Filtrado Tiva';
            app.PasaAltasButton_2 = uiradiobutton(app.FiltradoTivaButtonGroup);
            app.PasaAltasButton_2.Text = 'PasaAltas';
            app.PasaAltasButton_2.Value = true;
            app.PasaBajasButton_2 = uiradiobutton(app.FiltradoTivaButtonGroup);
            app.PasaBajasButton_2.Text = 'PasaBajas';
            app.PasaBandaButton_2 = uiradiobutton(app.FiltradoTivaButtonGroup);
            app.PasaBandaButton_2.Text = 'PasaBanda';
            app.RechazaBandaButton_2 = uiradiobutton(app.FiltradoTivaButtonGroup);
            app.RechazaBandaButton_2.Text = 'RechazaBanda';
            app.SaveCSVButton = uibutton(app.UIFigure, 'push');
            app.SaveCSVButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCSVButtonPushed, true);
            app.SaveCSVButton.Text = 'SaveCSV';
            app.SaveMatButton = uibutton(app.UIFigure, 'push');
            app.SaveMatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMatButtonPushed, true);
            app.SaveMatButton.Text = 'SaveMat';
            app.MagendBSwitchLabel = uilabel(app.UIFigure);
            app.MagendBSwitchLabel.Text = 'Mag en dB';
            app.MagendBSwitch = uiswitch(app.UIFigure, 'slider');
            app.MagendBSwitch.ValueChangedFcn = createCallbackFcn(app, @MagendBSwitchValueChanged, true);
            app.Frec_CorteSliderLabel = uilabel(app.UIFigure);
            app.Frec_CorteSliderLabel.Text = 'Frec_Corte';
            app.Frec_CorteSlider = uislider(app.UIFigure);
            app.Frec_CorteSlider.Limits = [1 500];
            app.Frec_CorteSlider.ValueChangedFcn = createCallbackFcn(app, @Frec_CorteSliderValueChanged, true);
            app.Frec_CorteSlider.Value = 1;
            app.Frec_Corte_SupSliderLabel = uilabel(app.UIFigure);
            app.Frec_Corte_SupSliderLabel.Text = 'Frec_Corte_Sup';
            app.Frec_Corte_SupSlider = uislider(app.UIFigure);
            app.Frec_Corte_SupSlider.Limits = [1 500];
            app.Frec_Corte_SupSlider.ValueChangedFcn = createCallbackFcn(app, @Frec_Corte_SupSliderValueChanged, true);
            app.Frec_Corte_SupSlider.Value = 1;
            app.OrdenSpinnerLabel = uilabel(app.UIFigure);
            app.OrdenSpinnerLabel.Text = 'Orden';
            app.OrdenSpinner = uispinner(app.UIFigure);
            app.OrdenSpinner.Limits = [1 Inf];
            app.OrdenSpinner.ValueChangedFcn = createCallbackFcn(app, @OrdenSpinnerValueChanged, true);
            app.OrdenSpinner.Value = 1;
            app.Frec_CorteSlider_2Label = uilabel(app.UIFigure);
            app.Frec_CorteSlider_2Label.Text = 'Frec_Corte';
            app.Frec_CorteSlider_2 = uislider(app.UIFigure);
            app.Frec_CorteSlider_2.Limits = [1 500];
            app.Frec_CorteSlider_2.ValueChangedFcn = createCallbackFcn(app, @Frec_CorteSlider_2ValueChanged, true);
            app.Frec_CorteSlider_2.Value = 1;
            app.Frec_Corte_SupSlider_2Label = uilabel(app.UIFigure);
            app.Frec_Corte_SupSlider_2Label.Text = 'Frec_Corte_Sup';
            app.Frec_Corte_SupSlider_2 = uislider(app.UIFigure);
            app.Frec_Corte_SupSlider_2.Limits = [1 500];
            app.Frec_Corte_SupSlider_2.ValueChangedFcn = createCallbackFcn(app, @Frec_Corte_SupSlider_2ValueChanged, true);
            app.Frec_Corte_SupSlider_2.Value = 1;
            app.OrdenSpinner_2Label = uilabel(app.UIFigure);
            app.OrdenSpinner_2Label.Text = 'Orden';
            app.OrdenSpinner_2 = uispinner(app.UIFigure);
            app.OrdenSpinner_2.Limits = [1 4];
            app.OrdenSpinner_2.ValueChangedFcn = createCallbackFcn(app, @OrdenSpinner_2ValueChanged, true);
            app.OrdenSpinner_2.Value = 1;
            app.RealtimeFilterButton = uibutton(app.UIFigure, 'push');
            app.RealtimeFilterButton.ButtonPushedFcn = createCallbackFcn(app, @RealtimeFilterButtonPushed, true);
            app.RealtimeFilterButton.Text = 'RealtimeFilter';
            app.TipoFiltroListBox_2Label = uilabel(app.UIFigure);
            app.TipoFiltroListBox_2Label.Text = 'TipoFiltro';
            app.TipoFiltroListBox_2 = uilistbox(app.UIFigure);
            app.TipoFiltroListBox_2.Items = {'IIR_Chebyshev1', 'IIR_Chebyshev2', 'IIR_Butterworth', ''};
            app.TipoFiltroListBox_2.Value = 'IIR_Butterworth';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.Editable = 'on';
            app.VariablesNode = uitreenode(app.Tree);
            app.VariablesNode.Text = 'Variables';
            app.Captured_SignalNode = uitreenode(app.VariablesNode);
            app.Captured_SignalNode.Text = 'Captured_Signal';
            app.Imported_SignalNode = uitreenode(app.VariablesNode);
            app.Imported_SignalNode.Text = 'Imported_Signal';
            app.Realtime_filterNode = uitreenode(app.VariablesNode);
            app.Realtime_filterNode.Text = 'Realtime_filter';
            app.Static_filterNode = uitreenode(app.VariablesNode);
            app.Static_filterNode.Text = 'Static_filter';
            app.Tree2 = uitree(app.UIFigure, 'checkbox');
            app.SignalsNode = uitreenode(app.Tree2);
            app.SignalsNode.Text = 'Signals';
            app.CapturedNode = uitreenode(app.SignalsNode);
            app.CapturedNode.Text = 'Captured';
            app.LoadedNode = uitreenode(app.SignalsNode);
            app.LoadedNode.Text = 'Loaded';
            app.SampleRateCheckBox = uicheckbox(app.UIFigure);
            app.SampleRateCheckBox.ValueChangedFcn = createCallbackFcn(app, @SampleRateCheckBoxValueChanged, true);
            app.SampleRateCheckBox.Text = 'SampleRate';
            app.SampleRateCheckBox.Value = true;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Amp_OGTab = uitab(app.TabGroup);
            app.Amp_OGTab.Title = 'Amp_OG';
            app.UIAxes6 = uiaxes(app.Amp_OGTab);
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Filt_MagTab = uitab(app.TabGroup);
            app.Filt_MagTab.Title = 'Filt_Mag';
            app.UIAxes5 = uiaxes(app.Filt_MagTab);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.Filt_PhaTab = uitab(app.TabGroup);
            app.Filt_PhaTab.Title = 'Filt_Pha';
            app.UIAxes7 = uiaxes(app.Filt_PhaTab);
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.Filt_TTab = uitab(app.TabGroup);
            app.Filt_TTab.Title = 'Filt_T';
            app.UIAxes4 = uiaxes(app.Filt_TTab);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Amp_FiltTab = uitab(app.TabGroup);
            app.Amp_FiltTab.Title = 'Amp_Filt';
            app.UIAxes8 = uiaxes(app.Amp_FiltTab);
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.Amp_OGTab_2 = uitab(app.TabGroup_2);
            app.Amp_OGTab_2.Title = 'Amp_OG';
            app.UIAxes6_2 = uiaxes(app.Amp_OGTab_2);
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            zlabel(app.UIAxes6_2, 'Z')
            app.Filt_Mag_TivaTab = uitab(app.TabGroup_2);
            app.Filt_Mag_TivaTab.Title = 'Filt_Mag_Tiva';
            app.UIAxes5_2 = uiaxes(app.Filt_Mag_TivaTab);
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.FIlt_Pha_TivaTab = uitab(app.TabGroup_2);
            app.FIlt_Pha_TivaTab.Title = 'FIlt_Pha_Tiva';
            app.UIAxes10 = uiaxes(app.FIlt_Pha_TivaTab);
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.Filt_TTab_2 = uitab(app.TabGroup_2);
            app.Filt_TTab_2.Title = 'Filt_T';
            app.UIAxes4_2 = uiaxes(app.Filt_TTab_2);
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            zlabel(app.UIAxes4_2, 'Z')
            app.Amp_FiltTab_2 = uitab(app.TabGroup_2);
            app.Amp_FiltTab_2.Title = 'Amp_Filt';
            app.UIAxes9 = uiaxes(app.Amp_FiltTab_2);
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.ApplyFilterButton = uibutton(app.UIFigure, 'state');
            app.ApplyFilterButton.ValueChangedFcn = createCallbackFcn(app, @ApplyFilterButtonValueChanged, true);
            app.ApplyFilterButton.Text = 'ApplyFilter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end